classdef InterfazNew < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ActividadFisica                matlab.ui.control.EditField
        ActividadFisicaEditFieldLabel  matlab.ui.control.Label
        Alcohol                        matlab.ui.control.EditField
        AlcoholEditFieldLabel          matlab.ui.control.Label
        Fuma                           matlab.ui.control.EditField
        FumaEditFieldLabel             matlab.ui.control.Label
        CalcularButton_2               matlab.ui.control.Button
        VariableDropDownLabel          matlab.ui.control.Label
        VariableDropDown               matlab.ui.control.DropDown
        Media                          matlab.ui.control.NumericEditField
        MediaEditFieldLabel            matlab.ui.control.Label
        DesviacionEstandar             matlab.ui.control.NumericEditField
        DesviacinEstandarLabel         matlab.ui.control.Label
        CalcularpMAButton              matlab.ui.control.Button
        IMCButton                      matlab.ui.control.Button
        DatosButton                    matlab.ui.control.Button
        GraficaButton                  matlab.ui.control.Button
        PMA                            matlab.ui.control.NumericEditField
        PMAEditFieldLabel              matlab.ui.control.Label
        CLASF                          matlab.ui.control.EditField
        CASIMC                         matlab.ui.control.NumericEditField
        IMCEditFieldLabel              matlab.ui.control.Label
        Edad                           matlab.ui.control.NumericEditField
        EdadEditFieldLabel             matlab.ui.control.Label
        ID                             matlab.ui.control.NumericEditField
        IDdelusuarioEditFieldLabel     matlab.ui.control.Label
        GraficaDropDown                matlab.ui.control.DropDown
        GraficaDropDownLabel           matlab.ui.control.Label
        ANALISISBASEDEDATOSLabel       matlab.ui.control.Label
        Grafica                        matlab.ui.control.UIAxes
    end

 properties (Access = private)
        HeartData
    end
    
    methods (Access = private)

        function Preprocesamiento(app)
            app.HeartData.height = app.HeartData.height / 100;
        end
        
        function edad(app)
            app.HeartData.age = app.HeartData.age / 365; 
        end

        function alcohol(app)
            app.HeartData.alco = categorical(app.HeartData.alco, [0 1], {'No Toma', 'Toma'});
        end

        function fumar(app)
            app.HeartData.smoke = categorical(app.HeartData.smoke, [0 1], {'No Fuma', 'Fuma'});
        end
        
        function Actividad(app)
            app.HeartData.active = categorical(app.HeartData.active, [0 1], {'No Act.Fisica', 'Hace Act.Fisica'});
        end  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('heart_data.mat', 'heart_data');
            app.HeartData = heart_data;
            app.Preprocesamiento();
            app.edad();
            app.alcohol();
            app.fumar();
            app.Actividad();
        end

        % Button pushed function: DatosButton
        function DatosButtonPushed(app, event)
            id = app.ID.Value;

            edad = app.HeartData.age(id);
            app.Edad.Value = edad; 

            fumar = app.HeartData.smoke(id);
            app.Fuma.Value = string(fumar);

            alcohol = app.HeartData.alco(id);
            app.Alcohol.Value = string(alcohol);
            
            actividadfisica = app.HeartData.active(id);
            app.ActividadFisica.Value = string(actividadfisica); 

        end

        % Value changed function: ID
        function IDValueChanged(app, event)
            id = app.ID.Value;
            
        end

        % Value changed function: GraficaDropDown
        function GraficaDropDownValueChanged(app, event)
            value = app.GraficaDropDown.Value;
            app.GraficaDropDown.Value = value;
        end

        % Value changed function: CASIMC
        function CASIMCValueChanged(app, event)
            value = app.CASIMC.Value;
            
        end

        % Button pushed function: IMCButton
        function IMCButtonPushed(app, event)
            id = app.ID.Value;
            peso = app.HeartData.weight(id);
            altura = app.HeartData.height(id);
        
            imc = (peso / (altura^2));
        
            app.CASIMC.Value = imc; 
        
            if (imc >= 17) && (imc <= 18.49) 
                app.CLASF.Value = "Delgadez leve";
            elseif (imc >= 18.5) && (imc <= 24.9) 
                app.CLASF.Value = "Normal";
            elseif (imc >= 25) && (imc <= 29.99) 
                app.CLASF.Value = "Sobrepeso";
            elseif (imc >= 30) && (imc <= 34.99)
                app.CLASF.Value = "Obesidad grado 1";
            elseif (imc >= 35) && (imc <= 39.99) 
                app.CLASF.Value = "Obesidad grado 2";
            elseif (imc >= 40)
                app.CLASF.Value = "Obesidad grado 3";
            end

        end

        % Button pushed function: CalcularpMAButton
        function CalcularpMAButtonPushed(app, event)
            id = app.ID.Value;
            psis = app.HeartData.ap_hi(id);
            pdia = app.HeartData.ap_lo(id);
            partmed = (((psis)+(2*pdia))/(3));
            app.PMA.Value = partmed;

        end

        % Drop down opening function: VariableDropDown
        function VariableDropDownOpening(app, event)
            
        end

        % Value changed function: VariableDropDown
        function VariableDropDownValueChanged(app, event)
        value = app.VariableDropDown.Value;
        app.VariableDropDownLabel.Text = value;
        end

        % Button pushed function: CalcularButton_2
        function CalcularButton_2Pushed(app, event)
                       
          Value = app.VariableDropDown.Value;

    if (strcmp(Value, 'IMC'))
        IMC2 = zeros(1, length(app.HeartData.weight));
        for i = 1:length(app.HeartData.age)
            peso = app.HeartData.weight(i);
            altura = app.HeartData.height(i);
            IMC2(i) = peso / altura^2;
        end
            app.DesviacionEstandar.Value = std(IMC2);
            app.Media.Value = mean(IMC2);
       
    elseif (strcmp(Value, 'PAM (Presión Arterial Media)'))
            pma = zeros(1, length(app.HeartData.ap_hi));
        for i = 1:length(app.HeartData.ap_hi)
            psis = app.HeartData.ap_hi(i);
            pdia = app.HeartData.ap_lo(i);
            pma(i) = (((psis) + (2 * pdia)) / (3));
        end
            app.DesviacionEstandar.Value = std(pma);
            app.Media.Value = mean(pma);
    
    elseif (strcmp(Value, 'Colesterol'))  
            app.DesviacionEstandar.Value = std(app.HeartData.cholesterol);
            app.Media.Value = mean(app.HeartData.cholesterol);
        end
        end

        % Button pushed function: GraficaButton
        function GraficaButtonPushed(app, event)
           Value = app.GraficaDropDown.Value;
             if (strcmp(Value, 'Enfermedad Cardiovascular (CVD)'))
                 cla(app.Grafica,"reset");
            hombresmen40 = 0;
            Mujeresmen40 = 0;
            hombres4050 = 0;
            Mujeres4050 = 0;
            hombres5060 = 0;
            Mujeres5060 = 0;
            hombres60m = 0;
            Mujeres60m = 0;

            for i = 1:length(app.HeartData.age)
                if app.HeartData.cardio(i) == 1 
                    if app.HeartData.age(i) <= 40
                        if app.HeartData.gender(i) == 1
                            hombresmen40 = hombresmen40 + 1;
                        elseif app.HeartData.gender(i) == 2
                            Mujeresmen40 = Mujeresmen40 + 1;
                        end
                    
                    elseif app.HeartData.age(i) > 40 && app.HeartData.age(i) <= 50
                        if app.HeartData.gender(i) == 1
                            hombres4050 = hombres4050 + 1;
                        elseif app.HeartData.gender(i) == 2
                            Mujeres4050 = Mujeres4050 + 1;
                        end
            
                            elseif app.HeartData.age(i) > 50 && app.HeartData.age(i) <= 60
                        if app.HeartData.gender(i) == 1
                            hombres5060 = hombres5060 + 1;
                        elseif app.HeartData.gender(i) == 2
                            Mujeres5060 = Mujeres5060+ 1;
                        end
                    
                    elseif app.HeartData.age(i) > 60
            
                        if app.HeartData.gender(i) == 1
                            hombres60m = hombres60m + 1;
                        elseif app.HeartData.gender(i) == 2
                            Mujeres60m = Mujeres60m + 1;
                        end
                    end
                end
            end
            x = [ "Menores de  40años", "40años a 50años", "50años a 60años","60años o más",];
            y = [hombresmen40 Mujeresmen40; hombres4050 Mujeres4050; hombres5060 Mujeres5060;hombres60m Mujeres60m];
                bar(app.Grafica,x,y)
                set(get(app.Grafica(1),'ylabel'),'string','Usuarios');
                set(get(app.Grafica(1),'xlabel'),'string','Edad (años)');
                set(get(app.Grafica(1),'title'),'string','Enfermedad cardiovascular (CVD)');
                legend(app.Grafica,'Hombres','Mujeres');
                set(app.Grafica, 'FontName', 'Comic Sans MS');
                grid(app.Grafica, 'on');
            
                         elseif (strcmp(Value, 'IMC vs Colesterol'))
                             cla(app.Grafica,"reset");
                             concardioIMC = zeros(1, length(app.HeartData.age));
                    sincardioIMC = zeros(1, length(app.HeartData.age));
                    concardioCol = zeros(1, length(app.HeartData.age));
                    sincardioCol = zeros(1, length(app.HeartData.age));
                    IMC2 = zeros(1, length(app.HeartData.age));
                    
                    
                    for i = 1:length(app.HeartData.age)
            
                        peso=app.HeartData.weight(i);
                        altura=app.HeartData.height(i);
                        IMC2(i) = peso/altura^2;
            
                        if app.HeartData.cardio(i) == 1
                            concardioIMC(i)=concardioIMC(i)+IMC2(i);
                            concardioCol(i)=concardioCol(i)+ app.HeartData.cholesterol(i);
                        elseif app.HeartData.cardio(i) == 0
                            sincardioIMC(i)=sincardioIMC(i)+IMC2(i);
                            sincardioCol(i)=sincardioCol(i)+app.HeartData.cholesterol(i);
                        end
                    end
                    
                    
                    scatter(app.Grafica,concardioCol,concardioIMC,'MarkerFaceColor', 'y');
                    hold(app.Grafica,'on');
                    scatter(app.Grafica,sincardioCol, sincardioIMC,'MarkerFaceColor', 'm');
                    hold(app.Grafica,'on');
                    set(get(app.Grafica(1),'xlabel'),'string','COLESTEROL');
                    set(get(app.Grafica(1),'ylabel'),'string','IMC');
                    set(get(app.Grafica(1),'title'),'string',' IMC vs COLESTEROL');
                    legend(app.Grafica,'Con CVD','Sin CVD');
                    set(app.Grafica, 'FontName', 'Comic Sans MS');
                    grid(app.Grafica, 'on');
            
                         elseif (strcmp(Value, 'Glucosa vs Presion Arterial'))
                             cla(app.Grafica,"reset");
                             concardioGlu = zeros(1, length(app.HeartData.age));
                    sincardioGlu = zeros(1, length(app.HeartData.age));
                    concardioPres = zeros(1, length(app.HeartData.age));
                    sincardioPres = zeros(1, length(app.HeartData.age));
                    PresionMedia = zeros(1, length(app.HeartData.age));
            
                    for i = 1:length(app.HeartData.age)
                        presionsis=app.HeartData.ap_hi(i);
                        presiondias=app.HeartData.ap_lo(i);
                        PresionMedia(i)=(((presionsis)+(2*presiondias))/(3));
            
                        if app.HeartData.cardio(i)== 1
                            concardioGlu(i)=concardioGlu(i)+app.HeartData.gluc(i);
                            concardioPres(i)=concardioPres(i)+PresionMedia(i);
                        elseif app.HeartData.cardio(i)== 0
                            sincardioGlu(i)=sincardioGlu(i)+app.HeartData.gluc(i);
                            sincardioPres(i)=sincardioPres(i)+PresionMedia(i);
                        end
                    end
                   
                    scatter(app.Grafica,concardioPres, concardioGlu, 'MarkerFaceColor', 'b');
                    hold(app.Grafica,'on');
                    scatter(app.Grafica,sincardioPres, sincardioGlu, 'MarkerFaceColor', 'm');
                    hold(app.Grafica,'on');
                    set(get(app.Grafica(1),'xlabel'),'string','PRESION ARTERIAL MEDIA (PMA)');
                    set(get(app.Grafica(1),'ylabel'),'string','GLUCOSA');
                    set(get(app.Grafica(1),'title'),'string',' GLUCOSA VS PRESION ARTERIAL');      
                    legend(app.Grafica,'Con enfermedad cardíaca','Sin enfermedad cardiaca');
                    set(app.Grafica, 'FontName', 'Comic Sans MS');
                    grid(app.Grafica, 'on');
            
               end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Grafica = uiaxes(app.UIFigure);
            title(app.Grafica, 'Title')
            xlabel(app.Grafica, 'X')
            ylabel(app.Grafica, 'Y')
            zlabel(app.Grafica, 'Z')
            app.ANALISISBASEDEDATOSLabel = uilabel(app.UIFigure);
            app.ANALISISBASEDEDATOSLabel.Text = '= ANALISIS BASE DE DATOS =';
            app.GraficaDropDownLabel = uilabel(app.UIFigure);
            app.GraficaDropDownLabel.Text = 'Grafica';
            app.GraficaDropDown = uidropdown(app.UIFigure);
            app.GraficaDropDown.Items = {'Enfermedad Cardiovascular (CVD)', 'IMC vs Colesterol', 'Glucosa vs Presion Arterial'};
            app.GraficaDropDown.ValueChangedFcn = createCallbackFcn(app, @GraficaDropDownValueChanged, true);
            app.GraficaDropDown.Value = 'Enfermedad Cardiovascular (CVD)';
            app.IDdelusuarioEditFieldLabel = uilabel(app.UIFigure);
            app.IDdelusuarioEditFieldLabel.Text = 'ID del usuario';
            app.ID = uieditfield(app.UIFigure, 'numeric');
            app.ID.Limits = [1 70000];
            app.ID.ValueChangedFcn = createCallbackFcn(app, @IDValueChanged, true);
            app.ID.Value = 1;
            app.EdadEditFieldLabel = uilabel(app.UIFigure);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.Edad = uieditfield(app.UIFigure, 'numeric');
            app.IMCEditFieldLabel = uilabel(app.UIFigure);
            app.IMCEditFieldLabel.Text = 'IMC';
            app.CASIMC = uieditfield(app.UIFigure, 'numeric');
            app.CASIMC.ValueChangedFcn = createCallbackFcn(app, @CASIMCValueChanged, true);
            app.CLASF = uieditfield(app.UIFigure, 'text');
            app.PMAEditFieldLabel = uilabel(app.UIFigure);
            app.PMAEditFieldLabel.Text = 'PMA';
            app.PMA = uieditfield(app.UIFigure, 'numeric');
            app.GraficaButton = uibutton(app.UIFigure, 'push');
            app.GraficaButton.ButtonPushedFcn = createCallbackFcn(app, @GraficaButtonPushed, true);
            app.GraficaButton.Text = 'Grafica';
            app.DatosButton = uibutton(app.UIFigure, 'push');
            app.DatosButton.ButtonPushedFcn = createCallbackFcn(app, @DatosButtonPushed, true);
            app.DatosButton.Text = 'Datos';
            app.IMCButton = uibutton(app.UIFigure, 'push');
            app.IMCButton.ButtonPushedFcn = createCallbackFcn(app, @IMCButtonPushed, true);
            app.IMCButton.Text = 'IMC';
            app.CalcularpMAButton = uibutton(app.UIFigure, 'push');
            app.CalcularpMAButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularpMAButtonPushed, true);
            app.CalcularpMAButton.Text = 'Calcular pMA';
            app.DesviacinEstandarLabel = uilabel(app.UIFigure);
            app.DesviacinEstandarLabel.Text = 'Desviación Estandar';
            app.DesviacionEstandar = uieditfield(app.UIFigure, 'numeric');
            app.MediaEditFieldLabel = uilabel(app.UIFigure);
            app.MediaEditFieldLabel.Text = 'Media';
            app.Media = uieditfield(app.UIFigure, 'numeric');
            app.VariableDropDown = uidropdown(app.UIFigure);
            app.VariableDropDown.Items = {'IMC', 'PAM (Presión Arterial Media)', 'Colesterol'};
            app.VariableDropDown.DropDownOpeningFcn = createCallbackFcn(app, @VariableDropDownOpening, true);
            app.VariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableDropDownValueChanged, true);
            app.VariableDropDown.Value = 'IMC';
            app.VariableDropDownLabel = uilabel(app.UIFigure);
            app.VariableDropDownLabel.Text = 'Variable';
            app.CalcularButton_2 = uibutton(app.UIFigure, 'push');
            app.CalcularButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.CalcularButton_2.Text = 'Calcular';
            app.FumaEditFieldLabel = uilabel(app.UIFigure);
            app.FumaEditFieldLabel.Text = 'Fuma';
            app.Fuma = uieditfield(app.UIFigure, 'text');
            app.AlcoholEditFieldLabel = uilabel(app.UIFigure);
            app.AlcoholEditFieldLabel.Text = 'Alcohol';
            app.Alcohol = uieditfield(app.UIFigure, 'text');
            app.ActividadFisicaEditFieldLabel = uilabel(app.UIFigure);
            app.ActividadFisicaEditFieldLabel.Text = 'Actividad Fisica';
            app.ActividadFisica = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfazNew

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end