classdef intfz_heartdata < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SenfeLabel                  matlab.ui.control.Label
        CenfeLabel                  matlab.ui.control.Label
        sinmedia                    matlab.ui.control.EditField
        sindesviac                  matlab.ui.control.EditField
        DatosButton                 matlab.ui.control.StateButton
        conmedia                    matlab.ui.control.EditField
        condesviac                  matlab.ui.control.EditField
        IMCClasif                   matlab.ui.control.EditField
        IMC                         matlab.ui.control.EditField
        PMA                         matlab.ui.control.EditField
        Alcohol                     matlab.ui.control.EditField
        Fuma                        matlab.ui.control.EditField
        Edad                        matlab.ui.control.EditField
        MediaLabel                  matlab.ui.control.Label
        DesviacionestandarLabel     matlab.ui.control.Label
        GraficarButton              matlab.ui.control.Button
        GraficaDropDown             matlab.ui.control.DropDown
        GraficaDropDownLabel        matlab.ui.control.Label
        Variable                    matlab.ui.control.DropDown
        VariableDropDownLabel       matlab.ui.control.Label
        CacularButton               matlab.ui.control.Button
        CalcularPMAButton           matlab.ui.control.StateButton
        CalcularIMCButton           matlab.ui.control.StateButton
        AlcoholLabel                matlab.ui.control.Label
        FumaLabel                   matlab.ui.control.Label
        EdadLabel                   matlab.ui.control.Label
        ID                          matlab.ui.control.EditField
        IDdelusuarioEditFieldLabel  matlab.ui.control.Label
        ANALISISBASEDEDATOSLabel    matlab.ui.control.Label
        graficasxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        HeartData
    end
    
    methods (Access = private)

             function Preprocesamiento(app)
            app.HeartData.height = app.HeartData.height / 100;
            
        end
        
        function edad(app)
            app.HeartData.age = app.HeartData.age/365;
            
        end

        
        function alcohol(app)
            app.HeartData.alco = categorical(app.HeartData.alco, [0 1], {'No Toma', 'Toma'});
        end

        function fumar(app)
            app.HeartData.smoke = categorical(app.HeartData.smoke, [0 1], {'No Fuma', 'Fuma'});
        end
    end  
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
  
            load("HeartData.mat", "heart_data");
            app.HeartData = heart_data;
        end

        % Value changed function: CalcularPMAButton
        function CalcularPMAButtonValueChanged(app, event)
            id = app.ID.Value;
            psis = app.HeartData.ap_hi(id);
            pdia = app.HeartData.ap_lo(id);
            partmed = (((psis)+(2*pdia))/(3));
            app.PMA = num2str(partmed, "%.2f");
        end

        % Value changed function: Edad
        function EdadValueChanged(app, event)
            id = app.id.Value;
            app.Edad = app.Edad(id);
        end

        % Value changed function: IMC
        function IMCValueChanged(app, event)
            value = app.IMC.Value;
            
        end

        % Value changed function: CalcularIMCButton
        function CalcularIMCButtonValueChanged(app, event)
            id = app.ID.Value;
            peso = app.HeartData.weight(id);
            altura = app.HeartData.height(id);
            imc = peso/altura^2;
            app.IMC.Text = num2str(imc, "%.2f");
            

        end

        % Value changed function: ID
        function IDValueChanged(app, event)
            id = app.ID.Value;
        end

        % Value changed function: DatosButton
        function DatosButtonValueChanged(app, event)
            id = app.ID.Value;

            edad =  (app.HeartData.age(id));
            app.Edad.Value = num2str(edad, "%.2f");

            fumar = (app.HeartData.smoke(id));
            app.Fuma.Value = num2str(fumar, "%.2f");

            alcohol = (app.HeartData.alco(id));
            app.Alcohol.Value = num2str(alcohol, "%.2f");

           
        end

        % Button pushed function: CacularButton
        function CacularButtonPushed(app, event)
            value = app. Variable.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.graficasxes = uiaxes(app.UIFigure);
            title(app.graficasxes, 'Title')
            xlabel(app.graficasxes, 'X')
            ylabel(app.graficasxes, 'Y')
            zlabel(app.graficasxes, 'Z')
            app.ANALISISBASEDEDATOSLabel = uilabel(app.UIFigure);
            app.ANALISISBASEDEDATOSLabel.Text = '= ANALISIS BASE DE DATOS =';
            app.IDdelusuarioEditFieldLabel = uilabel(app.UIFigure);
            app.IDdelusuarioEditFieldLabel.Text = 'ID del usuario';
            app.ID = uieditfield(app.UIFigure, 'text');
            app.ID.ValueChangedFcn = createCallbackFcn(app, @IDValueChanged, true);
            app.EdadLabel = uilabel(app.UIFigure);
            app.EdadLabel.Text = 'Edad:';
            app.FumaLabel = uilabel(app.UIFigure);
            app.FumaLabel.Text = 'Fuma:';
            app.AlcoholLabel = uilabel(app.UIFigure);
            app.AlcoholLabel.Text = 'Alcohol:';
            app.CalcularIMCButton = uibutton(app.UIFigure, 'state');
            app.CalcularIMCButton.ValueChangedFcn = createCallbackFcn(app, @CalcularIMCButtonValueChanged, true);
            app.CalcularIMCButton.Text = 'Calcular IMC';
            app.CalcularPMAButton = uibutton(app.UIFigure, 'state');
            app.CalcularPMAButton.ValueChangedFcn = createCallbackFcn(app, @CalcularPMAButtonValueChanged, true);
            app.CalcularPMAButton.Text = 'Calcular PMA';
            app.CacularButton = uibutton(app.UIFigure, 'push');
            app.CacularButton.ButtonPushedFcn = createCallbackFcn(app, @CacularButtonPushed, true);
            app.CacularButton.Text = 'Cacular';
            app.VariableDropDownLabel = uilabel(app.UIFigure);
            app.VariableDropDownLabel.Text = 'Variable ';
            app.Variable = uidropdown(app.UIFigure);
            app.Variable.Items = {'IMC', 'PAM', 'Colesterol'};
            app.Variable.Value = 'IMC';
            app.GraficaDropDownLabel = uilabel(app.UIFigure);
            app.GraficaDropDownLabel.Text = 'Grafica';
            app.GraficaDropDown = uidropdown(app.UIFigure);
            app.GraficaDropDown.Items = {'CVD', 'IMC vs Colesterol', 'Nivel de glucosa vs PAM'};
            app.GraficaDropDown.Value = 'CVD';
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.Text = 'Graficar';
            app.DesviacionestandarLabel = uilabel(app.UIFigure);
            app.DesviacionestandarLabel.Text = 'Desviacion estandar';
            app.MediaLabel = uilabel(app.UIFigure);
            app.MediaLabel.Text = 'Media';
            app.Edad = uieditfield(app.UIFigure, 'text');
            app.Edad.ValueChangedFcn = createCallbackFcn(app, @EdadValueChanged, true);
            app.Fuma = uieditfield(app.UIFigure, 'text');
            app.Alcohol = uieditfield(app.UIFigure, 'text');
            app.PMA = uieditfield(app.UIFigure, 'text');
            app.IMC = uieditfield(app.UIFigure, 'text');
            app.IMC.ValueChangedFcn = createCallbackFcn(app, @IMCValueChanged, true);
            app.IMCClasif = uieditfield(app.UIFigure, 'text');
            app.condesviac = uieditfield(app.UIFigure, 'text');
            app.conmedia = uieditfield(app.UIFigure, 'text');
            app.DatosButton = uibutton(app.UIFigure, 'state');
            app.DatosButton.ValueChangedFcn = createCallbackFcn(app, @DatosButtonValueChanged, true);
            app.DatosButton.Text = 'Datos';
            app.sindesviac = uieditfield(app.UIFigure, 'text');
            app.sinmedia = uieditfield(app.UIFigure, 'text');
            app.CenfeLabel = uilabel(app.UIFigure);
            app.CenfeLabel.Text = 'C. enfe';
            app.SenfeLabel = uilabel(app.UIFigure);
            app.SenfeLabel.Text = 'S. enfe';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = intfz_heartdata

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end